classdef GUI1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        PlotGraphCheckBox               matlab.ui.control.CheckBox
        MaximumTimesSpinnerLabel        matlab.ui.control.Label
        MaximumTimesSpinner             matlab.ui.control.Spinner
        TimeStepSpinnerLabel            matlab.ui.control.Label
        TimeStepSpinner                 matlab.ui.control.Spinner
        TileThicknessmmSpinnerLabel     matlab.ui.control.Label
        TileThicknessmmSpinner          matlab.ui.control.Spinner
        ThicknessSpatialStepSpinnerLabel  matlab.ui.control.Label
        ThicknessSpatialStepSpinner     matlab.ui.control.Spinner
        RunButton                       matlab.ui.control.Button
        DMethodDropDownLabel            matlab.ui.control.Label
        DMethodDropDown                 matlab.ui.control.DropDown
        TileMaterialDropDownLabel       matlab.ui.control.Label
        TileMaterialDropDown            matlab.ui.control.DropDown
        TileLocationDropDownLabel       matlab.ui.control.Label
        TileLocationDropDown            matlab.ui.control.DropDown
        Image                           matlab.ui.control.Image
        Switch_2                        matlab.ui.control.RockerSwitch
        TileWidthmmSpinnerLabel         matlab.ui.control.Label
        TileWidthmmSpinner              matlab.ui.control.Spinner
        WidthSpatialStepSpinnerLabel    matlab.ui.control.Label
        WidthSpatialStepSpinner         matlab.ui.control.Spinner
        MaxInnerTileTempKorFSpinner     matlab.ui.control.Spinner
        MaxInnerTileTempKorFSpinnerLabel  matlab.ui.control.Label
        AutomaticTileThicknessCheckBox  matlab.ui.control.CheckBox
        TemperaturerequiredifautomaticthicknessselectedLabel  matlab.ui.control.Label
        DMethodLabel                    matlab.ui.control.Label
        DMethodDropDown_2               matlab.ui.control.DropDown
        RightPanel                      matlab.ui.container.Panel
        DPlotLabel                      matlab.ui.control.Label
        DPlotLabel_2                    matlab.ui.control.Label
        AutomaticTileThicknessmmEditFieldLabel  matlab.ui.control.Label
        AutomaticTileThicknessmmEditField  matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MaxInnerTileTempKorFSpinner.Visible = 'off'
            app.TemperaturerequiredifautomaticthicknessselectedLabel.Visible = 'off'
            app.MaxInnerTileTempKorFSpinnerLabel.Visible = 'off'
            app.AutomaticTileThicknessmmEditFieldLabel.Visible = 'off'
            app.AutomaticTileThicknessmmEditField.Visible = 'off'
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if app.AutomaticTileThicknessCheckBox.Value
                xmax = minThicknessInv(app.MaxInnerTileTempKorFSpinner.Value, app.MaximumTimesSpinner.Value, app.TimeStepSpinner.Value, app.ThicknessSpatialStepSpinner.Value, app.DMethodDropDown.Value, str2num(app.Switch_2.Value), false)
            else
                xmax = app.TileThicknessmmSpinner.Value / 1000
            end
            app.AutomaticTileThicknessmmEditField.Value = xmax*1000
            shuttle(app.MaximumTimesSpinner.Value, app.TimeStepSpinner.Value, xmax, app.ThicknessSpatialStepSpinner.Value, app.DMethodDropDown.Value, app.PlotGraphCheckBox.Value, str2num(app.Switch_2.Value), app.TileMaterialDropDown.Value, app.TileLocationDropDown.Value, true, app.UIAxes2, app.UIAxes2_2, app.UIAxes);   
            close(gcf) % closes window
            shuttle2d(app.MaximumTimesSpinner.Value, app.TimeStepSpinner.Value, app.TileThicknessmmSpinner.Value / 1000, app.ThicknessSpatialStepSpinner.Value, app.TileWidthmmSpinner.Value / 1000,  app.WidthSpatialStepSpinner.Value, app.DMethodDropDown_2.Value, app.PlotGraphCheckBox.Value, str2num(app.Switch_2.Value), app.TileMaterialDropDown.Value, app.TileLocationDropDown.Value, app.UIAxes_2); 
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Callback function
        function Run2DButtonPushed(app, event)
            [x, t, u] = shuttle(app.MaximumTimesSpinner.Value, app.TimeStepSpinner.Value, app.TileThicknessmmSpinner.Value / 1000, app.ThicknessSpatialStepSpinner.Value, app.DMethodDropDown.Value, app.PlotGraphCheckBox.Value, str2num(app.Switch_2.Value), app.TileMaterialDropDown.Value, app.TileLocationDropDown.Value, app.UIAxes);          
        end

        % Value changed function: AutomaticTileThicknessCheckBox
        function AutomaticTileThicknessCheckBoxValueChanged(app, event)
            value = app.AutomaticTileThicknessCheckBox.Value;
            if value
                app.MaxInnerTileTempKorFSpinner.Visible = 'on'
                app.TemperaturerequiredifautomaticthicknessselectedLabel.Visible = 'on'
                app.MaxInnerTileTempKorFSpinnerLabel.Visible = 'on'
                app.AutomaticTileThicknessmmEditFieldLabel.Visible = 'on'
                app.AutomaticTileThicknessmmEditField.Visible = 'on'
            else
                app.MaxInnerTileTempKorFSpinner.Visible = 'off'
                app.TemperaturerequiredifautomaticthicknessselectedLabel.Visible = 'off'
                app.MaxInnerTileTempKorFSpinnerLabel.Visible = 'off'
                app.AutomaticTileThicknessmmEditFieldLabel.Visible = 'off'
                app.AutomaticTileThicknessmmEditField.Visible = 'off'
            end
        
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {784, 784};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {326, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PlotGraphCheckBox = uicheckbox(app.LeftPanel);
            app.PlotGraphCheckBox.Text = 'Plot Graph';
            app.PlotGraphCheckBox.Value = true;
            app.MaximumTimesSpinnerLabel = uilabel(app.LeftPanel);
            app.MaximumTimesSpinnerLabel.Text = 'Maximum Time (s)';
            app.MaximumTimesSpinner = uispinner(app.LeftPanel);
            app.MaximumTimesSpinner.Limits = [0 Inf];
            app.MaximumTimesSpinner.Value = 4000;
            app.TimeStepSpinnerLabel = uilabel(app.LeftPanel);
            app.TimeStepSpinnerLabel.Text = 'Time Step';
            app.TimeStepSpinner = uispinner(app.LeftPanel);
            app.TimeStepSpinner.Limits = [1 2000];
            app.TimeStepSpinner.Value = 1001;
            app.TileThicknessmmSpinnerLabel = uilabel(app.LeftPanel);
            app.TileThicknessmmSpinnerLabel.Text = 'Tile Thickness (mm)';
            app.TileThicknessmmSpinner = uispinner(app.LeftPanel);
            app.TileThicknessmmSpinner.Step = 0.01;
            app.TileThicknessmmSpinner.Limits = [0 100];
            app.TileThicknessmmSpinner.Value = 50;
            app.ThicknessSpatialStepSpinnerLabel = uilabel(app.LeftPanel);
            app.ThicknessSpatialStepSpinnerLabel.Text = 'Thickness Spatial Step';
            app.ThicknessSpatialStepSpinner = uispinner(app.LeftPanel);
            app.ThicknessSpatialStepSpinner.Step = 0.5;
            app.ThicknessSpatialStepSpinner.Limits = [1 50];
            app.ThicknessSpatialStepSpinner.Value = 21;
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = {'Run'; ''};
            app.DMethodDropDownLabel = uilabel(app.LeftPanel);
            app.DMethodDropDownLabel.Text = '1D Method';
            app.DMethodDropDown = uidropdown(app.LeftPanel);
            app.DMethodDropDown.Items = {'Forward', 'Dufort-frankel', 'Backward', 'Crank-nicolson'};
            app.DMethodDropDown.ItemsData = {'forward', 'dufort-frankel', 'backward', 'crank-nicolson'};
            app.DMethodDropDown.Value = 'forward';
            app.TileMaterialDropDownLabel = uilabel(app.LeftPanel);
            app.TileMaterialDropDownLabel.Text = 'Tile Material';
            app.TileMaterialDropDown = uidropdown(app.LeftPanel);
            app.TileMaterialDropDown.Items = {'LI-900', 'LI2200', 'FRCP-12'};
            app.TileMaterialDropDown.ItemsData = {'li900', 'li2200', 'frcp12'};
            app.TileMaterialDropDown.Value = 'li900';
            app.TileLocationDropDownLabel = uilabel(app.LeftPanel);
            app.TileLocationDropDownLabel.Text = 'Tile Location';
            app.TileLocationDropDown = uidropdown(app.LeftPanel);
            app.TileLocationDropDown.Items = {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H'};
            app.TileLocationDropDown.ItemsData = {'597', '502', '590', '468', '480', '850', '711', '730'};
            app.TileLocationDropDown.Value = '597';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'Shuttle.png';
            app.Switch_2 = uiswitch(app.LeftPanel, 'rocker');
            app.Switch_2.Items = {'Fahrenheit', 'Kelvin'};
            app.Switch_2.ItemsData = {'0', '1'};
            app.Switch_2.Value = '1';
            app.TileWidthmmSpinnerLabel = uilabel(app.LeftPanel);
            app.TileWidthmmSpinnerLabel.Text = 'Tile Width (mm)';
            app.TileWidthmmSpinner = uispinner(app.LeftPanel);
            app.TileWidthmmSpinner.Step = 0.01;
            app.TileWidthmmSpinner.Limits = [0 500];
            app.TileWidthmmSpinner.Value = 200;
            app.WidthSpatialStepSpinnerLabel = uilabel(app.LeftPanel);
            app.WidthSpatialStepSpinnerLabel.Text = 'Width Spatial Step';
            app.WidthSpatialStepSpinner = uispinner(app.LeftPanel);
            app.WidthSpatialStepSpinner.Step = 0.5;
            app.WidthSpatialStepSpinner.Limits = [1 100];
            app.WidthSpatialStepSpinner.Value = 81;
            app.MaxInnerTileTempKorFSpinner = uispinner(app.LeftPanel);
            app.MaxInnerTileTempKorFSpinner.Step = 0.01;
            app.MaxInnerTileTempKorFSpinner.Limits = [300 1000];
            app.MaxInnerTileTempKorFSpinner.Value = 500;
            app.MaxInnerTileTempKorFSpinnerLabel = uilabel(app.LeftPanel);
            app.MaxInnerTileTempKorFSpinnerLabel.Text = 'Max Inner Tile Temp (K or F)';
            app.AutomaticTileThicknessCheckBox = uicheckbox(app.LeftPanel);
            app.AutomaticTileThicknessCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutomaticTileThicknessCheckBoxValueChanged, true);
            app.AutomaticTileThicknessCheckBox.Text = 'Automatic Tile Thickness';
            app.TemperaturerequiredifautomaticthicknessselectedLabel = uilabel(app.LeftPanel);
            app.TemperaturerequiredifautomaticthicknessselectedLabel.Text = {'Tile Thickness parameter will be ignored.'; ' Temperature parameter required:'};
            app.DMethodLabel = uilabel(app.LeftPanel);
            app.DMethodLabel.Text = '2D Method';
            app.DMethodDropDown_2 = uidropdown(app.LeftPanel);
            app.DMethodDropDown_2.Items = {'Forward', 'Backward'};
            app.DMethodDropDown_2.ItemsData = {'forward', 'backward'};
            app.DMethodDropDown_2.Value = 'forward';
            app.RightPanel = uipanel(app.GridLayout);
            app.DPlotLabel = uilabel(app.RightPanel);
            app.DPlotLabel.Text = '1D Plot';
            app.DPlotLabel_2 = uilabel(app.RightPanel);
            app.DPlotLabel_2.Text = '2D Plot';
            app.AutomaticTileThicknessmmEditFieldLabel = uilabel(app.RightPanel);
            app.AutomaticTileThicknessmmEditFieldLabel.Text = 'Automatic Tile Thickness (mm)';
            app.AutomaticTileThicknessmmEditField = uieditfield(app.RightPanel, 'numeric');
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, {'Space Shuttle Temperature when entering'; ' earth''s atmosphere '})
            xlabel(app.UIAxes, 'Tile Thickness (m)')
            ylabel(app.UIAxes, 'Time (s)')
            zlabel(app.UIAxes, 'Temperature')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, {'Space Shuttle Temperature when entering'; ' earth''s atmosphere '})
            xlabel(app.UIAxes_2, 'Tile Thickness (m)')
            ylabel(app.UIAxes_2, 'Tile Width (m)')
            zlabel(app.UIAxes_2, 'Temperature')
            app.UIAxes_2.ZLim = [0 1500];
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Outside Temperature of Tile')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Temperature')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.RightPanel);
            title(app.UIAxes2_2, 'Inside Temperature of Tile')
            xlabel(app.UIAxes2_2, 'Time (s)')
            zlabel(app.UIAxes2_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end